classdef Heat2D_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        useslidertostepthroughtimeframesSlider  matlab.ui.control.Slider
        useslidertostepthroughtimeframesSliderLabel  matlab.ui.control.Label
        STATUSLabel                  matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        TerminalOutputTextArea       matlab.ui.control.TextArea
        TerminalOutputTextAreaLabel  matlab.ui.control.Label
        RightTempEditField           matlab.ui.control.NumericEditField
        RightTempEditFieldLabel      matlab.ui.control.Label
        LeftTempEditField            matlab.ui.control.NumericEditField
        LeftTempEditFieldLabel       matlab.ui.control.Label
        BottomTempEditField          matlab.ui.control.NumericEditField
        BottomTempEditFieldLabel     matlab.ui.control.Label
        TopTempEditField             matlab.ui.control.NumericEditField
        TopTempEditFieldLabel        matlab.ui.control.Label
        BoundaryConditionsLabel      matlab.ui.control.Label
        ThermalDiffusivityEditField  matlab.ui.control.NumericEditField
        ThermalDiffusivityEditFieldLabel  matlab.ui.control.Label
        InitialConditionsLabel       matlab.ui.control.Label
        InitialTempDistributionEditField  matlab.ui.control.NumericEditField
        InitialTempDistributionEditFieldLabel  matlab.ui.control.Label
        UNITSDropDown                matlab.ui.control.DropDown
        UNITSDropDownLabel           matlab.ui.control.Label
        YNodesEditField              matlab.ui.control.NumericEditField
        YNodesEditFieldLabel         matlab.ui.control.Label
        XNodesEditField              matlab.ui.control.NumericEditField
        XNodesEditFieldLabel         matlab.ui.control.Label
        YLengthEditField             matlab.ui.control.NumericEditField
        YLengthEditFieldLabel        matlab.ui.control.Label
        XLengthEditField             matlab.ui.control.NumericEditField
        XLengthmftLabel              matlab.ui.control.Label
        GRIDParametersLabel          matlab.ui.control.Label
        SOLVERParametersLabel        matlab.ui.control.Label
        PlottingIntervalsEditField   matlab.ui.control.NumericEditField
        PlottingIntervalsLabel       matlab.ui.control.Label
        TotalRuntimesEditField       matlab.ui.control.NumericEditField
        TotalRuntimesLabel           matlab.ui.control.Label
        TIMEParametersLabel          matlab.ui.control.Label
        TimeStepsEditField           matlab.ui.control.NumericEditField
        TimeStepsLabel               matlab.ui.control.Label
        SolverMethodDropDown         matlab.ui.control.DropDown
        SolverMethodDropDownLabel    matlab.ui.control.Label
        SolverTypeDropDown           matlab.ui.control.DropDown
        SolverTypeDropDownLabel      matlab.ui.control.Label
        DHeatTransferSolverLabel     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    %% Initialize properties to store simulation time frames
    properties (Access = private)
        configs
        units
        solution_global
        dt
        tfinal
        plot_freq
    end
    
    %% Setting status label based on certain inputs being valid
    methods (Access = private)
        
        function func(app)

            % Read required input values
            dt = app.TimeStepsEditField.Value;
            tfinal = app.TotalRuntimesEditField.Value;
            plottingfreq = app.PlottingIntervalsEditField.Value;

            xlength    = app.XLengthEditField.Value;
            ylength    = app.YLengthEditField.Value;

            alpha = app.ThermalDiffusivityEditField.Value;

            % Check if all required inputs are valid
            if dt>0 && tfinal>0 && plottingfreq>0 && xlength>0 && ylength>0 && alpha>0
                app.STATUSLabel.Text = "Status: Ready to Run";
                app.STATUSLabel.BackgroundColor = [0 1 0];

            else
                app.STATUSLabel.Text = "Status: Not Ready to Run";
                app.STATUSLabel.BackgroundColor = [1 0 0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% Set Path for source files
            projectRoot = fileparts(mfilename('fullpath'));
            addpath(genpath(fullfile(projectRoot,'src')));
            addpath(genpath(fullfile(projectRoot,'plotting')));
            
            %% Auto-selects Metric units value for correct labels to appear
            UNITSDropDownValueChanged(app, []);  % force label update

            %% Sets status indicator
            app.STATUSLabel.Text = "Status: Not Ready to Run";
            app.STATUSLabel.BackgroundColor = [1 0 0];

        end

        % Value changed function: UNITSDropDown
        function UNITSDropDownValueChanged(app, event)
           
            %% Updates certain labels based on units selection
            unitsChoice = app.UNITSDropDown.Value;

            isMetric = contains(unitsChoice, "Metric", "IgnoreCase", true);

            if isMetric
                app.XLengthmftLabel.Text = "X Length [m]";
                app.YLengthEditFieldLabel.Text = "Y Length [m]";

                app.InitialTempDistributionEditFieldLabel.Text = "Initial Temp. Distribution [*C]";
                app.ThermalDiffusivityEditFieldLabel.Text = "Thermal Diffusivity [m^2/s]";

                app.TopTempEditFieldLabel.Text = "Top Temp. [*C]";
                app.BottomTempEditFieldLabel.Text = "Bottom Temp. [*C]";
                app.LeftTempEditFieldLabel.Text = "Left Temp. [*C]";
                app.RightTempEditFieldLabel.Text = "Right Temp. [*C]";


            else
                app.XLengthmftLabel.Text = "X Length [ft]";
                app.YLengthEditFieldLabel.Text = "Y Length [ft]";

                app.InitialTempDistributionEditFieldLabel.Text = "Initial Temp. Distribution [*F]";
                app.ThermalDiffusivityEditFieldLabel.Text = "Thermal Diffusivity [ft^2/hr]";

                app.TopTempEditFieldLabel.Text = "Top Temp. [*F]";
                app.BottomTempEditFieldLabel.Text = "Bottom Temp. [*F]";
                app.LeftTempEditFieldLabel.Text = "Left Temp. [*F]";
                app.RightTempEditFieldLabel.Text = "Right Temp. [*F]";
            end

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %% Actions immediately upon Run button push
            tic; 
            app.STATUSLabel.Text = "Status: Running";
            app.STATUSLabel.BackgroundColor = [1 1 0];
            drawnow;


            %% Re-Building Configs Structure (bypass configs.m)  

            % Units
            unitsChoice = app.UNITSDropDown.Value;
            isMetric = contains(unitsChoice, "Metric", "IgnoreCase", true);
            
            if isMetric
                configs.units = 1;
            else
                configs.units = 2;
            end
            
            % Solver
            solverType = app.SolverTypeDropDown.Value;
            solverMethod = string(app.SolverMethodDropDown.Value);

            if contains(solverType,"FTCS","IgnoreCase",true)
                solver.method = "heat2D_FTCS.m";   
                solver.type   = 0;               
            else
                solver.method = "heat2D_BTCS.m";   
                switch string(solverMethod)
                    case "Inverse A (A-1b)"
                        solver.type = 1;

                    case "Built-In A (Ab)"
                        solver.type = 2;

                    case "LU Decomposition of A (A=LU)"
                        solver.type = 3;

                    otherwise
                        solver.type = 2;
                end
            end
            configs.solver = solver;

            % Time 
            time.step = app.TimeStepsEditField.Value;
            time.end = app.TotalRuntimesEditField.Value;
            time.plottingfreq = app.PlottingIntervalsEditField.Value;
            configs.time = time;

            % Grid 
            grid.L = app.XLengthEditField.Value;   
            grid.W = app.YLengthEditField.Value; 
            grid.nodesX = app.XNodesEditField.Value;
            grid.nodesY = app.YNodesEditField.Value;
            configs.grid = grid;

            % Initial Conditions
            IC.alpha = app.ThermalDiffusivityEditField.Value;
            IC.T0 = app.InitialTempDistributionEditField.Value;
            
            if configs.units == 2
                IC.alpha = IC.alpha / 3600;
            end

            configs.IC = IC;

            % Boundary Conditions
            BC.Ttop = app.TopTempEditField.Value;
            BC.Tbottom = app.BottomTempEditField.Value;
            BC.Tleft = app.LeftTempEditField.Value;
            BC.Tright = app.RightTempEditField.Value;
            configs.BC = BC;

            %% Runs Solver

            % Calls heat2D_FTCS simulation if chosen
            if (configs.solver.method == "heat2D_FTCS.m")
                solution = heat2D_FTCS(configs);
            end

            % Calls heat2D_BTCS simulation if chosen
            if (configs.solver.method == "heat2D_BTCS.m")
                solution = heat2D_BTCS(configs);    
            end

            runtime = toc;
            
            % Sets gloabl variables
            app.solution_global = solution; 
            app.dt = configs.time.step;
            app.tfinal = configs.time.end;
            app.plot_freq = configs.time.plottingfreq;
            app.configs = configs; 
            app.units = configs.units; 

            %% Congifuring Output Terminal
            
            % Runtime
            app.TerminalOutputTextArea.Value = sprintf("Total Runtime: %.2f s", runtime);

            % Solver type, method
            app.TerminalOutputTextArea.Value = [app.TerminalOutputTextArea.Value; sprintf("Solver Used: %s, %s", app.SolverTypeDropDown.Value, app.SolverMethodDropDown.Value)];

            % Determining Solution Stabilty
            if contains(solverType,"FTCS","IgnoreCase",true)            
                l  = app.configs.grid.L;   
                w  = app.configs.grid.W;  
                xN = app.configs.grid.nodesX;  
                yN = app.configs.grid.nodesY;
                
                xC = xN - 1; 
                yC = yN -1;
                dx = l/xC; 
                dy = w/yC;
                
                Fx = configs.IC.alpha*configs.time.step/(dx^2);
                Fy = configs.IC.alpha*configs.time.step/(dy^2);

                Fo = Fx + Fy;

                if (Fo > 0.5)
                    app.TerminalOutputTextArea.Value = [app.TerminalOutputTextArea.Value; splitlines(evalc('disp("Combined Fourier Number > 0.5, solution is unstable")'))];
                    app.STATUSLabel.Text = "Satus: Error";
                    app.STATUSLabel.BackgroundColor = [1 0 0];

                else
                    app.STATUSLabel.Text = "Status: Simulation Complete";
                    app.STATUSLabel.BackgroundColor = [0 1 0];
                end

            end

            %% Setting Slider

            total_frames = length(solution); 
            app.useslidertostepthroughtimeframesSlider.Value = 1;
            app.useslidertostepthroughtimeframesSlider.Limits = [1 total_frames];
            app.useslidertostepthroughtimeframesSlider.Step = 1;
            useslidertostepthroughtimeframesSliderValueChanged(app, []);

        end

        % Value changed function: useslidertostepthroughtimeframesSlider
        function useslidertostepthroughtimeframesSliderValueChanged(app, event)
            %% Plots Temperature Distribution

            % Updates frame label (seconds)
            time = (app.useslidertostepthroughtimeframesSlider.Value - 1) * app.plot_freq; 
            
            % Pulls current time frame temperature distribution
            Tn = app.solution_global(app.useslidertostepthroughtimeframesSlider.Value).Tn;
            
            % Plot temperature distribution on UIAxes
            Tmin = min(app.solution_global(1).Tn(:));
            Tmax = max(app.solution_global(1).Tn(:));

            l  = app.configs.grid.L;   
            w  = app.configs.grid.W;  
            xN = app.configs.grid.nodesX;  
            yN = app.configs.grid.nodesY;

            x = linspace(0, l, xN);
            y = linspace(w, 0, yN); 

            current_plot = app.UIAxes;
            cla(current_plot);
            
            imagesc(current_plot, x, y', Tn);
            clim(current_plot, [Tmin Tmax]);
            set(current_plot, 'YDir', 'normal');
            axis(current_plot, 'equal', 'tight');
            colormap(current_plot, jet);
            colorbar(current_plot);

            if app.configs.units == 1
                xlabel(current_plot, "x (m)");
                ylabel(current_plot, "y (m)");
            end

            if app.configs.units ==2
                xlabel(current_plot, "x (ft)");
                ylabel(current_plot, "y (ft)");
            end

            title(current_plot, sprintf("Temperature Distribution at Time = %.2f s", time));


        end

        % Value changed function: TimeStepsEditField
        function TimeStepsEditFieldValueChanged(app, event)
            %% Checks user input for status label
            func(app);
        end

        % Value changed function: TotalRuntimesEditField
        function TotalRuntimesEditFieldValueChanged(app, event)
            %% Checks user input for status label
            func(app);
        end

        % Value changed function: PlottingIntervalsEditField
        function PlottingIntervalsEditFieldValueChanged(app, event)
            %% Checks user input for status label 
            func(app);
        end

        % Value changed function: XLengthEditField
        function XLengthEditFieldValueChanged(app, event)
            %% Checks user input for status label            
            func(app);
        end

        % Value changed function: YLengthEditField
        function YLengthEditFieldValueChanged(app, event)
            %% Checks user input for status label            
            func(app);
        end

        % Value changed function: XNodesEditField
        function XNodesEditFieldValueChanged(app, event)
            %% Checks user input for status label
            func(app);
        end

        % Value changed function: YNodesEditField
        function YNodesEditFieldValueChanged(app, event)
            %% Checks user input for status label
            func(app);
        end

        % Value changed function: ThermalDiffusivityEditField
        function ThermalDiffusivityEditFieldValueChanged(app, event)
            %% Checks user input for status label
            func(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            
            %% Resets everything upon button push 
         
            app.STATUSLabel.Text = "Status: Not Ready to Run";

            cla(app.UIAxes);
            colorbar(app.UIAxes,'off');

            app.TerminalOutputTextArea.Value = "";

            app.useslidertostepthroughtimeframesSlider.Value  = 1;
            app.useslidertostepthroughtimeframesSlider.Limits = [1 2];

            app.solution_global = [];
            app.dt = [];
            app.tfinal = [];
            app.plot_freq = [];

            app.SolverTypeDropDown.Value   = "FTCS";
            app.SolverMethodDropDown.Value = "None (FTCS)";

            app.TimeStepsEditField.Value = 0;
            app.TotalRuntimesEditField.Value = 0;
            app.PlottingIntervalsEditField.Value = 0;

            app.XLengthEditField.Value = 0;
            app.YLengthEditField.Value = 0;
            app.YNodesEditField.Value = 3;
            app.XNodesEditField.Value = 3; 

            app.ThermalDiffusivityEditField.Value = 0;

            app.TopTempEditField.Value = 0;
            app.BottomTempEditField.Value = 0;
            app.LeftTempEditField.Value = 0;
            app.RightTempEditField.Value = 0;
           
            func(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DHeatTransferSolverLabel = uilabel(app.GridLayout);
            app.DHeatTransferSolverLabel.Text = '2D Heat Transfer Solver';
            app.SolverTypeDropDownLabel = uilabel(app.GridLayout);
            app.SolverTypeDropDownLabel.Text = 'Solver Type';
            app.SolverTypeDropDown = uidropdown(app.GridLayout);
            app.SolverTypeDropDown.Items = {'FTCS', 'BTCS'};
            app.SolverTypeDropDown.Value = 'FTCS';
            app.SolverMethodDropDownLabel = uilabel(app.GridLayout);
            app.SolverMethodDropDownLabel.Text = 'Solver Method';
            app.SolverMethodDropDown = uidropdown(app.GridLayout);
            app.SolverMethodDropDown.Items = {'None (FTCS)', 'Inverse A (A-1b)', 'Built-In A (A\b)', 'LU Decomposition of A (A=LU)'};
            app.SolverMethodDropDown.Value = 'None (FTCS)';
            app.TimeStepsLabel = uilabel(app.GridLayout);
            app.TimeStepsLabel.Text = 'Time Step [s]';
            app.TimeStepsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeStepsEditField.Limits = [0 Inf];
            app.TimeStepsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStepsEditFieldValueChanged, true);
            app.TIMEParametersLabel = uilabel(app.GridLayout);
            app.TIMEParametersLabel.Text = 'TIME Parameters';
            app.TotalRuntimesLabel = uilabel(app.GridLayout);
            app.TotalRuntimesLabel.Text = 'Total Runtime [s]';
            app.TotalRuntimesEditField = uieditfield(app.GridLayout, 'numeric');
            app.TotalRuntimesEditField.Limits = [0 Inf];
            app.TotalRuntimesEditField.ValueChangedFcn = createCallbackFcn(app, @TotalRuntimesEditFieldValueChanged, true);
            app.PlottingIntervalsLabel = uilabel(app.GridLayout);
            app.PlottingIntervalsLabel.Text = 'Plotting Interval [s]';
            app.PlottingIntervalsEditField = uieditfield(app.GridLayout, 'numeric');
            app.PlottingIntervalsEditField.Limits = [0 Inf];
            app.PlottingIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingIntervalsEditFieldValueChanged, true);
            app.SOLVERParametersLabel = uilabel(app.GridLayout);
            app.SOLVERParametersLabel.Text = 'SOLVER Parameters';
            app.GRIDParametersLabel = uilabel(app.GridLayout);
            app.GRIDParametersLabel.Text = 'GRID Parameters';
            app.XLengthmftLabel = uilabel(app.GridLayout);
            app.XLengthmftLabel.Text = 'X Length';
            app.XLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.XLengthEditField.Limits = [0 Inf];
            app.XLengthEditField.ValueChangedFcn = createCallbackFcn(app, @XLengthEditFieldValueChanged, true);
            app.YLengthEditFieldLabel = uilabel(app.GridLayout);
            app.YLengthEditFieldLabel.Text = 'Y Length';
            app.YLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.YLengthEditField.Limits = [0 Inf];
            app.YLengthEditField.ValueChangedFcn = createCallbackFcn(app, @YLengthEditFieldValueChanged, true);
            app.XNodesEditFieldLabel = uilabel(app.GridLayout);
            app.XNodesEditFieldLabel.Text = 'X Nodes';
            app.XNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.XNodesEditField.Limits = [3 Inf];
            app.XNodesEditField.ValueChangedFcn = createCallbackFcn(app, @XNodesEditFieldValueChanged, true);
            app.XNodesEditField.Value = 3;
            app.YNodesEditFieldLabel = uilabel(app.GridLayout);
            app.YNodesEditFieldLabel.Text = 'Y Nodes';
            app.YNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.YNodesEditField.Limits = [3 Inf];
            app.YNodesEditField.ValueChangedFcn = createCallbackFcn(app, @YNodesEditFieldValueChanged, true);
            app.YNodesEditField.Value = 3;
            app.UNITSDropDownLabel = uilabel(app.GridLayout);
            app.UNITSDropDownLabel.Text = 'UNITS';
            app.UNITSDropDown = uidropdown(app.GridLayout);
            app.UNITSDropDown.Items = {'Metric [m] [*C] [m^2/s]', 'Imperial [ft] [*F] [ft^2/hr]'};
            app.UNITSDropDown.ValueChangedFcn = createCallbackFcn(app, @UNITSDropDownValueChanged, true);
            app.UNITSDropDown.Value = 'Metric [m] [*C] [m^2/s]';
            app.InitialTempDistributionEditFieldLabel = uilabel(app.GridLayout);
            app.InitialTempDistributionEditFieldLabel.Text = 'Initial Temp. Distribution';
            app.InitialTempDistributionEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialConditionsLabel = uilabel(app.GridLayout);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.ThermalDiffusivityEditFieldLabel = uilabel(app.GridLayout);
            app.ThermalDiffusivityEditFieldLabel.Text = 'Thermal Diffusivity';
            app.ThermalDiffusivityEditField = uieditfield(app.GridLayout, 'numeric');
            app.ThermalDiffusivityEditField.Limits = [0 Inf];
            app.ThermalDiffusivityEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalDiffusivityEditFieldValueChanged, true);
            app.BoundaryConditionsLabel = uilabel(app.GridLayout);
            app.BoundaryConditionsLabel.Text = 'Boundary Conditions';
            app.TopTempEditFieldLabel = uilabel(app.GridLayout);
            app.TopTempEditFieldLabel.Text = 'Top Temp.';
            app.TopTempEditField = uieditfield(app.GridLayout, 'numeric');
            app.BottomTempEditFieldLabel = uilabel(app.GridLayout);
            app.BottomTempEditFieldLabel.Text = 'Bottom Temp.';
            app.BottomTempEditField = uieditfield(app.GridLayout, 'numeric');
            app.LeftTempEditFieldLabel = uilabel(app.GridLayout);
            app.LeftTempEditFieldLabel.Text = 'Left Temp.';
            app.LeftTempEditField = uieditfield(app.GridLayout, 'numeric');
            app.RightTempEditFieldLabel = uilabel(app.GridLayout);
            app.RightTempEditFieldLabel.Text = 'Right Temp.';
            app.RightTempEditField = uieditfield(app.GridLayout, 'numeric');
            app.TerminalOutputTextAreaLabel = uilabel(app.GridLayout);
            app.TerminalOutputTextAreaLabel.Text = 'Terminal Output';
            app.TerminalOutputTextArea = uitextarea(app.GridLayout);
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.RESETButton = uibutton(app.GridLayout, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.STATUSLabel = uilabel(app.GridLayout);
            app.STATUSLabel.Text = 'STATUS';
            app.useslidertostepthroughtimeframesSliderLabel = uilabel(app.GridLayout);
            app.useslidertostepthroughtimeframesSliderLabel.Text = '(use slider to step through time frames)';
            app.useslidertostepthroughtimeframesSlider = uislider(app.GridLayout);
            app.useslidertostepthroughtimeframesSlider.MajorTicks = [];
            app.useslidertostepthroughtimeframesSlider.MajorTickLabels = {'0', '3', '6', '9', '12', '15', '18', '21', '24', '27', '30', '33', '36', '39', '42', '45', '48', '51', '54', '57', '60', '63', '66', '69', '72', '75', '78', '81', '84', '87', '90', '93', '96', '100'};
            app.useslidertostepthroughtimeframesSlider.ValueChangedFcn = createCallbackFcn(app, @useslidertostepthroughtimeframesSliderValueChanged, true);
            app.useslidertostepthroughtimeframesSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.useslidertostepthroughtimeframesSlider.Step = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heat2D_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end